classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnergySavingControlINwirelessSensorNetworksUIFigure  matlab.ui.Figure
        SaveButton              matlab.ui.control.Button
        BandwidthDropDown       matlab.ui.control.DropDown
        BandwidthDropDownLabel  matlab.ui.control.Label
        NodesDropDown           matlab.ui.control.DropDown
        NodesDropDownLabel      matlab.ui.control.Label
        Image6                  matlab.ui.control.Image
        Panel                   matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        ClearButton             matlab.ui.control.Button
        TheRequestButton        matlab.ui.control.Button
        InfoAboutNodeButton     matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        Image5_2                matlab.ui.control.Image
        Image5                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
    end

    
    methods (Access = public)
        function startupFcn(app1)
    % Make the main app window maximized
    app.EnergySavingControlINwirelessSensorNetworksUIFigure.WindowState = 'maximized';
    % Show the UI figure after all components are created
    app.EnergySavingControlINwirelessSensorNetworksUIFigure.Visible = 'on';
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global table1;
global nodeData;
 nodeData=[];
    table1={};
% hObject    handle to pushbutton1 (see GCBO)
% eventdata  reserved - to be defined in a future version of MATLAB
% handles    structure with handles and user data (see GUIDATA)

% Demonstrate the random spread of a group of stations
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Assume minimum distance in X, and Y between
% stations

string_table=[];   
% Assume number of stations n
counter=0;   

%  for n=16:2:20   
switch app.NodesDropDown.Value
    case "6"
        n=6;
        case "8"
        n=8;
        case "10"
        n=10;
        case "12"
        n=12;
        case "14"
        n=14;
        case "16"
        n=16;
        case "18"
        n=18;
        case "20"
        n=20;
end
   
xx = rand(1, n)*3000;
yy = rand(1,n)*3000;  
% xmin=xx(1)-500;  
% xmax=xx(end)+500;  
% ymin=yy(1)-500;  
% ymax=yy(end)+500;  
% limits=[xmin xmax ymin ymax];  
% Random locations selection
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
for k=1:n
   xr=randi(length(xx));   
    xc(k)=xx(xr);  
    xi=find(xx ~= xc(k));
    xx=xx(xi);   
    yr=randi(length(yy));   
    yc(k)=yy(yr);  
    yi=find(yy ~= yc(k));
   yy=yy(yi);
end;
cen1=[xc',yc'];   
num1=1:n;
neighbor_limit=2000;  
%stations(cen1,handles,num1,limits,neighbor_limit);  
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%  figure('Position', [100 100 850 600]);

% Switch to axes1
% 
xxc=[];  
yyc=[];  
neighbor_xc=[];  
neighbor_yc=[];  
for k=1:length(num1)
    [xc yc]=circle([cen1(k,1) cen1(k,2)],120);    
    [xn yn]=circle([cen1(k,1) cen1(k,2)],1000);        
    xxc=[xxc,xc'];   
    yyc=[yyc,yc'];   
    neighbor_xc=[neighbor_xc,xn'];  
    neighbor_yc=[neighbor_yc,yn'];      
end;    

xxc=[xxc,neighbor_xc];   
yyc=[yyc,neighbor_yc];  
out=app.UIAxes;
axes(out);
plot(out,xxc,yyc);
axis(app.UIAxes, [0 4000 0 4000]);
for k = 1:length(num1)
    gt = text(app.UIAxes, cen1(k,1), cen1(k,2), num2str(num1(k)));  
    set(gt, 'Position', [cen1(k,1) - 100, cen1(k,2) - 50], 'FontSize', 12, 'FontWeight', 'bold');
end 

% saveas(gcf, fullfile('D:\gu-project\project\day 25.11.2023\save_graphic', 'node_destinations.jpg'));
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

dij=zeros(n,n);   

for k=1:n   
    for kk=1:n   
        dij(k,kk)=sqrt((cen1(k,1)-cen1(kk,1))^2+(cen1(k,2)-cen1(kk,2))^2);    
    end;
end;

% Calculate the stations within range to each one
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

for k=1:n 
    ss=['station',num2str(k)];   
    TH=[];
    P1=cen1(k,:);
    neighbor=find( (dij(k,:) > 0) & (dij(k,:) <= neighbor_limit));  
    if (length(neighbor) >=1)
        for kk=1:length(neighbor)
            P2=cen1(neighbor(kk),:);   
            theta=theta1(P1,P2);
            TH(kk)=theta;  
        end;
        eval([ss,'.neighborsNo = neighbor;']);   
        eval([ss,'.neighborsAng = TH;']);  
    else
        eval([ss,'.neighborsNo = [];']);   
        eval([ss,'.neighborsAng = [];']);  
    end;    
end;  

% Calculate the stations within range to each one
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
xij=zeros(n);
for k=1:n 
    ss=['station',num2str(k)];   
    k1=eval([ss,'.neighborsNo']);   
    xij(k,k1)=1;
end;       
nodeData = cell(n,2); % Cell array to store node data (node number, neighborsAng, neighborsNo)

% Populate nodeData
for k = 1:n
    % Get node number
    nodeData{k, 1} = k;
    
    % Get neighborsAng and neighborsNo for node k
    ss = ['station', num2str(k)];
    neighborsAng = eval([ss, '.neighborsAng']);
    neighborsNo = eval([ss, '.neighborsNo']);
    
    % Convert neighborsAng and neighborsNo to strings for display
    dd = round(neighborsAng(:));
    neighborsAngStr = num2str(dd(:)');
    neighborsNoStr = num2str(neighborsNo(:)');
    
    % Store neighborsAng and neighborsNo in nodeData
    nodeData{k, 2} = neighborsNoStr;
    nodeData{k, 3} = neighborsAngStr;
end
% Close the file

% assuming a value to alpha
%%%%%%%%%%%%%%%%%%%%%%%%%%%%
alpha=2;    
         
% Generate random numbers with mean lamdam and 
% variance lamdam/2
% Pick a request lamda_s_d
% 
%

lamdam=150;  
rr = lamdam + sqrt(lamdam/2).*randn(10000,1);

% var(r),mean(r)

% The traffic demand between node pair (s,d) lamda_sd
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% random request generation (s is row, d is column)
sd=randi([0 1],n);   
for k=1:n   
    sd(k,k)=0;
end;      

% Find the request in vector format ss and dd
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

[dd,ss]=find(sd');   

while length(dd)<15
sd=randi([0 1],n);   
for k=1:n   
    sd(k,k)=0;
end;      

% Find the request in vector format ss and dd
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

[dd,ss]=find(sd');   
end;    

% Select lamda_s_d
% Generate request matrix  
index1=1:length(dd);  
for k=1:length(dd)-3
    index=randi(length(rr));
    reqmat(k,1)=rr(index);   
    index=randi(length(dd));   
    reqmat(k,2)=ss(index);       
    reqmat(k,3)=dd(index);   
    index1=find(index1 ~= index);
    dd=dd(index1);       
    ss=ss(index1);    
    index1=1:length(dd);      
end;


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% for Band=500:500:1500     
  switch app.BandwidthDropDown.Value
    case "500"
        Band=500;
        case "700"
        Band=700;
        case "900"
        Band=900;
        case "1100"
        Band=1100;
        case "1300"
        Band=1300;
        case "1500"
        Band=1500;
    end
    
% the power matrix
%%%%%%%%%%%%%%%%%%%%%%
pij=dij.^alpha;    
    
% Initilization
%%%%%%%%%%%%%%%%

consP=0;   
Tlamda_sd=0;       

%%%%%%%%%%%%%%%%        
    
% consider number of requests=6
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
for nr=1:12     
    
% Pick any request    
%%%%%%%%%%%%%%%%%%%%
% kk=randi(size(reqmat,1));   

lamda_sd=reqmat(nr,1);   
s=reqmat(nr,2);   
d=reqmat(nr,3);   

% Generate the power vector
%%%%%%%%%%%%%%%%%%%%%%%%%%%
PVij=pij';   
PVij=PVij(:); 
PVij=PVij';    

% Assume that we have one request s -->d   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% The coefficient of the optimized function
% is PVij which is C .....
% We use the total power instead
% The unknowns are 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

CC=PVij;     
A_L_eq=[];   
B_L_eq=[];    
A_eq=[];
B_eq=[];   

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% The maximum power level Pmax
% Or use the total power
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Power constraint
% The total power
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

TPower=sum(sum(triu(pij).*triu(xij)));    
A_L_eq=[A_L_eq;CC];    
B_L_eq=[B_L_eq;TPower];     

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Delay constraint
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% delta_sd   
% less than or equal constraint
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Assume a value for delta_sd
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
delta_sd=4;  
A_L_eq=[A_L_eq;ones(1,n*n)];    
B_L_eq=[B_L_eq;delta_sd];     

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Bandwidth constraint
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% The Bandwidth B
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

AAA=[];   
for i=1:n   
    AA=zeros(n);  
    AA(i,:)=1;
    AA(:,i)=AA(:,i)+1;
    AA=AA';
    AA=AA(:);   
    AA=AA';
    AAA=[AAA;AA];   
end;    
A_L_eq=[A_L_eq;AAA];    
B_L_eq=[B_L_eq;Band/lamda_sd*ones(n,1)];    


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% New set of constraints
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
AA1=[];   
for i=1:n   
    AA=zeros(n);  
    AA(i,:)=1;
    AA(:,i)=AA(:,i)-1;
    AA=AA';
    AA=AA(:);   
    AA=AA';
    AA1=[AA1;AA];   
    if s==i
        BB=1;   
    elseif d==i   
        BB=-1;  
    else
        BB=0;   
    end;      
    B_eq=[B_eq;BB];         
end;    
A_eq=[A_eq;AA1];    


% Another set of less than or equal   
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

AA=eye(n*n);    
B=xij';
B=B(:);
A_L_eq=[A_L_eq;AA];    
B_L_eq=[B_L_eq;B];     

% Mixed integer linear programming function
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
lb=zeros(1,n*n);   
ub=ones(1,n*n);   
e=2^-24;   
M=[1:n*n];   
[x,minpower,status]=IP1(CC,A_L_eq,B_L_eq,A_eq,B_eq,lb,ub,M,e); 
 

if status ==1   
    xres=reshape(x,n,n);   
    xres=xres';   
    last=s;   
    pp1=[last]; 
    while (last ~= d)
        last=find(xres(last,:) == 1);
        pp1=[pp1,last];
    end;
end;    
if status == 1  
    path_string = [];  
    for k = 1:length(pp1) - 1
        path_string = [path_string, num2str(pp1(k)), '-->'];
    end
    path_string = [path_string, num2str(pp1(end))];    
    
    counter = counter + 1;   
    consP = minpower * 10^-12;  
    pij = pij + xres .* pij;  
    Tlamda_sd = lamda_sd;       
    
    % Update table1 with new data
    table1{counter, 1} = n;   
    table1{counter, 2} = s;   
    table1{counter, 3} = d;   
    table1{counter, 4} = Tlamda_sd;   
    table1{counter, 5} = Band;   
    table1{counter, 6} = consP;       
    table1{counter, 7} = path_string; % Adding path_string to the table
    
    % Update string_table with the path string
    string_table = strvcat(string_table, path_string);           
    
    % Optionally save the figure
    % figstr = ['fig', num2str(counter), '_s_', num2str(s), '_d_', num2str(d)];
    % saveas(gcf, figstr, 'jpg');  
end

end   % lamda_s_d   

% end   % Bandwidth    

  % number of nodes 


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Write the data to a text file
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

saveFolderPath = 'D:\Graduation Project\Final project\Final project\saving';

% Construct the full file path
filePath = fullfile(saveFolderPath, 'res_simulation5.txt');

% Open the file for writing
fid = fopen(filePath, 'w');
if fid == -1
    error('Cannot open file for writing: %s', filePath);
end

% Write header to the file
fprintf(fid, '%70s\n\n\n', datestr(now, 0));
fprintf(fid, '\t\t Ad Hoc, Power Saving Analysis \n');
fprintf(fid, '=================== Data Analysis ====================\n');
fprintf(fid, '==========================================================\n\n');
fprintf(fid, 'n\t s\t d\t lamda\t\t\t Bandwidth \t\t\t  route\t\t\t\t\t Power\r\n');

% Write data to the file
for k = 1:size(table1, 1)
    fprintf(fid, '%2d \t  %2d \t %2d  %10.4f \t\t %7d \t\t  %s \t\t\t\t %10.4e \r\n', ...
        table1{k, 1}, table1{k, 2}, table1{k, 3}, table1{k, 4}, table1{k, 5}, ...
        table1{k, 7}, table1{k, 6});
end

% Close the file
fclose(fid);

% Save route4 in the same folder
save('route4');
        end

        % Button pushed function: TheRequestButton
        function TheRequestButtonPushed(app, event)

    global table1;

    % Create a figure
    fig1 = figure('Position', [150, 100, 1150, 600], 'Name', 'REQUEST');

    % Set icon programmatically
    jFrame = get(handle(fig1), 'JavaFrame');
    jFrame.setFigureIcon(javax.swing.ImageIcon('eco-battery.ico'));

    % Load the background image
    backgroundImage = imread('starry-sky-2675322_1280.jpg'); % Replace with the path to your image

    % Display the background image
    ax = axes('Parent', fig1, 'Position', [0, 0, 1, 1]);
    imagesc(ax, backgroundImage);
    ax.Visible = 'off'; % Hide the axes

    % Create a uitable
    uitable('Parent', fig1, ...
        'Data', table1, ...
        'ColumnName', {'Nodes', 'Source', 'Destination', 'lamda_sd (bit)', 'Bandwidth (bit/s)', 'Energy consumption (µj)', 'Path'}, ...
        'ColumnWidth', {100, 100, 100, 200, 100, 200, 300}, ...
        'RowName', [], ...
        'Position', [10 10 1100 550], ...
        'FontSize', 18, ...
        'ColumnFormat', {'char', 'char', 'char', 'char', 'char', 'char', 'char'});
        
        end

        % Button pushed function: InfoAboutNodeButton
        function InfoAboutNodeButtonPushed(app, event)
          global nodeData;
 % Create a figure

    fig2 = figure('Position', [150, 100, 1200, 650], 'Name', 'InfoAboutNode');

% Set icon programmatically
javax.swing.ImageIcon('eco-battery.ico');
jFrame = get(handle(fig2), 'JavaFrame');
jFrame.setFigureIcon(javax.swing.ImageIcon('eco-battery.ico'));

    % Load the background image
    backgroundImage = imread('starry-sky-2675322_1280.jpg'); % Replace 'starry-sky-2675322_1280.jpg' with the path to your image

   
    % Create an axes to contain the background image
   ax = axes('Parent', fig2, 'Position', [0, 0, 1, 1]);
    % Display the image as the background
      imagesc(backgroundImage);
ax.Visible = 'off'; % Hide the axes
    % Set the title
    title('Info About Node');

    % Create a uitable to display node dat
    uitable('Parent', fig2, ...
            'Data', nodeData, ...
            'ColumnName', {'Node Number', 'Neighbors Nodes','Neighbors Angle'}, ...
            'ColumnWidth', {100,600,800}, ...
            'RowName', [], ...
            'Position', [10, 10, 1150, 600], ...
            'FontSize', 16, ... 
            'ColumnFormat', {'char', 'char','char'});
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
               global nodeData;
    global table1;
    
    % Clear the UIAxes
    try
        cla(app.UIAxes);
    catch
        disp('Failed to clear UIAxes');
    end

    % Clear the edit fields
    try
        app.NODESEditField.Value = '';
        app.NODESEditField_2.Value = '';
    catch
        disp('Failed to clear edit fields');
    end
    
    % Clear global variables
    nodeData = [];
    table1 = [];
    
% Create a custom figure window
fig = figure('Name', 'Success', 'NumberTitle', 'off', 'MenuBar', 'none', 'Resize', 'off', 'Position', [150, 200, 500, 125]);

% Load and display the background image
bg_image = imread('starry-sky-2675322_1280.jpg');
bg_axes = axes('Parent', fig, 'Units', 'normalized', 'Position', [0 0 1 1]);
imagesc(bg_image);
set(bg_axes, 'handlevisibility', 'off', 'visible','off');

% Create a panel to hold the message and icon
panel = uipanel('Parent', fig, 'Position', [0.1 0.3 0.8 0.4]);

% Add text message
uicontrol('Parent', panel, 'Style', 'text', 'String', ' Figures and UIAxes contents cleared successfully. ', ...
    'FontSize', 12,'FontWeight','bold', 'HorizontalAlignment', 'center', 'Units', 'normalized', 'Position', [0 0.5 1 .4],'ForegroundColor','0.00,0.45,0.74');

% Add custom icon
% icon_axes = axes('Parent', panel, 'Units', 'normalized', 'Position', [0.4 0.1 0.2 0.3]);
% icon_image = imread('eco-battery.ico');
% imshow(icon_image);

% Add an OK button
ok_button = uicontrol('Parent', fig, 'Style', 'pushbutton', 'String', 'OK','FontWeight','bold', 'Units', 'normalized', ...
    'Position', [0.4 0.05 0.2 0.2], 'Callback', 'close(gcf)');

% Make the panel background color transparent
set(panel, 'BackgroundColor', [0.80,0.80,0.80]); % [R, G, B, Alpha]

% Center the figure on the screen
movegui(fig, 'center');
  close(fig); % Close the current figure
    close all;  % Close all other figures
        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)
            position = app.Panel.Position;
            % This callback is called when the size of the main app window changes
    
    % Get the position and size of the main app window
    windowPosition = app.EnergySavingControlINwirelessSensorNetworksUIFigure.Position;
    
    % Update the position and size of the panel accordingly
    app.Panel.Position = [0 0 windowPosition(3) windowPosition(4)];
        end

        % Size changed function: 
        % EnergySavingControlINwirelessSensorNetworksUIFigure
        function EnergySavingControlINwirelessSensorNetworksUIFigureSizeChanged(app, event)
            position = app.EnergySavingControlINwirelessSensorNetworksUIFigure.Position;
            
        end

        % Value changed function: BandwidthDropDown
        function BandwidthDropDownValueChanged(app, event)
            value = app.BandwidthDropDown.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
    % Get the folder where you want to save the images 
    saveFolderPath = uigetdir('', 'D:\Graduation Project\Final project\DAY 20-4-2024\saving');
    
    % Find all figure objects
    figHandles = findobj('Type', 'figure');
    
    % Iterate through each figure and save it
    for i = 1:length(figHandles)
        figHandle = figHandles(i);
        
        % Construct the filename
        filename = sprintf('Figure_%d.png', i);
        
        % Construct the full file path
        filePath = fullfile(saveFolderPath, filename);
        
        % Save the figure as a PNG image using saveas
        saveas(figHandle, filePath, 'png');
    end
    
    % Find all UIAxes objects
    axesHandles = findobj(app.UIAxes, 'Type', 'axes');
    
    % Iterate through each UIAxes object and save its contents
    for j = 1:length(axesHandles)
        axHandle = axesHandles(j);
        
        % Construct the filename
        filename = sprintf('UIAxes_Content_%d.png', j);
        
        % Construct the full file path
        filePath = fullfile(saveFolderPath, filename);
        
        % Save the UIAxes contents as a PNG image using exportgraphics
        exportgraphics(axHandle, filePath, 'Resolution', 300);
    end


% Create a custom figure window
fig = figure('Name', 'Success', 'NumberTitle', 'off', 'MenuBar', 'none', 'Resize', 'off', 'Position', [100, 200, 500, 125]);

% Load and display the background image
bg_image = imread('starry-sky-2675322_1280.jpg');
bg_axes = axes('Parent', fig, 'Units', 'normalized', 'Position', [0 0 1 1]);
imagesc(bg_image);
set(bg_axes, 'handlevisibility', 'off', 'visible','off');

% Create a panel to hold the message and icon
panel = uipanel('Parent', fig, 'Position', [0.1 0.3 0.8 0.4]);

% Add text message
uicontrol('Parent', panel, 'Style', 'text', 'String', ' Figures and UIAxes contents saved successfully. ', ...
    'FontSize', 12,'FontWeight','bold', 'HorizontalAlignment', 'center', 'Units', 'normalized', 'Position', [0 0.5 1 .4],'ForegroundColor','0.00,0.45,0.74');


% Add an OK button
ok_button = uicontrol('Parent', fig, 'Style', 'pushbutton', 'String', 'OK','FontWeight','bold', 'Units', 'normalized', ...
    'Position', [0.4 0.05 0.2 0.2], 'Callback', 'close(gcf)');

% Make the panel background color transparent
set(panel, 'BackgroundColor', [0.80,0.80,0.80]); % [R, G, B, Alpha]

% Center the figure on the screen
movegui(fig, 'center');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EnergySavingControlINwirelessSensorNetworksUIFigure = uifigure('Visible', 'off');
            app.EnergySavingControlINwirelessSensorNetworksUIFigure.Name = 'Energy Saving Control IN wireless Sensor Networks';
            app.EnergySavingControlINwirelessSensorNetworksUIFigure.Icon = fullfile(pathToMLAPP, 'eco-battery.png');
            app.EnergySavingControlINwirelessSensorNetworksUIFigure.SizeChangedFcn = createCallbackFcn(app, @EnergySavingControlINwirelessSensorNetworksUIFigureSizeChanged, true);
            app.Image = uiimage(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'starry-sky-2675322_1280.jpg');
            app.Image5 = uiimage(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'starry-sky-2675322_1280.jpg');
            app.Image5_2 = uiimage(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.Image5_2.ImageSource = fullfile(pathToMLAPP, 'starry-sky-2675322_1280.jpg');
            app.StartButton = uibutton(app.EnergySavingControlINwirelessSensorNetworksUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.InfoAboutNodeButton = uibutton(app.EnergySavingControlINwirelessSensorNetworksUIFigure, 'push');
            app.InfoAboutNodeButton.ButtonPushedFcn = createCallbackFcn(app, @InfoAboutNodeButtonPushed, true);
            app.InfoAboutNodeButton.Text = 'Info About Node  ';
            app.TheRequestButton = uibutton(app.EnergySavingControlINwirelessSensorNetworksUIFigure, 'push');
            app.TheRequestButton.ButtonPushedFcn = createCallbackFcn(app, @TheRequestButtonPushed, true);
            app.TheRequestButton.Text = {'The Request'; ''};
            app.ClearButton = uibutton(app.EnergySavingControlINwirelessSensorNetworksUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Panel = uipanel(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Nodes Distribution')
            xlabel(app.UIAxes, 'Units in meters')
            ylabel(app.UIAxes, 'Units in meters')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'colorcube')
            app.Image6 = uiimage(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'eco-battery.png');
            app.NodesDropDownLabel = uilabel(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.NodesDropDownLabel.Text = 'Nodes';
            app.NodesDropDown = uidropdown(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.NodesDropDown.Items = {'6', '8', '10', '12', '14', '16', '18', '20'};
            app.NodesDropDown.Value = '6';
            app.BandwidthDropDownLabel = uilabel(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.BandwidthDropDownLabel.Text = 'Bandwidth';
            app.BandwidthDropDown = uidropdown(app.EnergySavingControlINwirelessSensorNetworksUIFigure);
            app.BandwidthDropDown.Items = {'500', '700', '900', '1100', '1300', '1500'};
            app.BandwidthDropDown.ValueChangedFcn = createCallbackFcn(app, @BandwidthDropDownValueChanged, true);
            app.BandwidthDropDown.Value = '500';
            app.SaveButton = uibutton(app.EnergySavingControlINwirelessSensorNetworksUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.EnergySavingControlINwirelessSensorNetworksUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnergySavingControlINwirelessSensorNetworksUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnergySavingControlINwirelessSensorNetworksUIFigure)
        end
    end
end